classdef AFM_simulation_3d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HollowtipSwitch               matlab.ui.control.Switch
        HollowtipSwitchLabel          matlab.ui.control.Label
        MultiplepeakstipSwitch        matlab.ui.control.Switch
        MultiplepeakstipSwitchLabel   matlab.ui.control.Label
        ShearedtipSwitch              matlab.ui.control.Switch
        ShearedtipSwitchLabel         matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        TappingstepsizemicromSpinner  matlab.ui.control.Spinner
        TappingstepsizemicromSpinnerLabel  matlab.ui.control.Label
        AnimationKnob                 matlab.ui.control.DiscreteKnob
        AnimationKnobLabel            matlab.ui.control.Label
        RandomButton                  matlab.ui.control.StateButton
        ContaminatedtipSwitch         matlab.ui.control.Switch
        ContaminatedtipSwitchLabel    matlab.ui.control.Label
        SkipAnimationButton           matlab.ui.control.StateButton
        AnimationspeedSlider          matlab.ui.control.Slider
        AnimationspeedSliderLabel     matlab.ui.control.Label
        WidthSlider                   matlab.ui.control.Slider
        WidthSliderLabel              matlab.ui.control.Label
        RadiusSlider                  matlab.ui.control.Slider
        RadiusSliderLabel             matlab.ui.control.Label
        SurfaceprofilesButtonGroup    matlab.ui.container.ButtonGroup
        RandomsurfaceButton           matlab.ui.control.RadioButton
        RectangleButton               matlab.ui.control.RadioButton
        InvertedtriangleButton        matlab.ui.control.RadioButton
        SemicircleButton              matlab.ui.control.RadioButton
        SineButton                    matlab.ui.control.RadioButton
        TriangleButton                matlab.ui.control.RadioButton
        SquareButton                  matlab.ui.control.RadioButton
        RunButton                     matlab.ui.control.Button
        TipShapeAxes                  matlab.ui.control.UIAxes
        SurfaceImagingAxes            matlab.ui.control.UIAxes
        SimulationAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        max_tip_radius; % max tip radius for normalization
        max_tip_radius_units; % units for the max tip radius in microns
        scale_bar; % scale bar width in mircrons
        max_half_width; % normalized max half width of tip at tip height
        tip_height; % normalized tip height;
        deltax; % normalized delta x
        x_sim_axes; % normalized vector of x values for simulation axes
        x_surf; % normalized vector of x values for surface plot
        y_surf; % normalized vector of y values for surface plot
        y_surf_imaging; % normalized vector of y values for surface for imaging
        random_state; % random state
        default_rand_nums_small_width; % default random numbers
        default_rand_nums_large_width; % default random numbers
        y_center_sim; % y center value of tip for simulation
        y_surf_img_sim; % y values of the surface image from simulation
        x_surf_img_sim_tapping; % normalized vector of x values for surface from tapping
        y_surf_img_sim_tapping; % normalized vector of y values for surface from tapping
        surf_img_line_width; % surface image line width
        surf_img_marker_size; % surface image marker size
    end
    
    methods (Access = private)
        % ========= Start: set default values =========
        function setDefaultValues(app)
            clc
            close all
            app.max_tip_radius = radiusSliderConversion(app, app.RadiusSlider.Limits(2));
            app.max_tip_radius_units = 10; % \mu m (microns)
            app.scale_bar = 5; % \mu m (microns)
            app.max_half_width = 1.5;
            app.tip_height = 6;
            app.deltax = 0.001;
            app.x_sim_axes = app.SimulationAxes.XLim(1):app.deltax:app.SimulationAxes.XLim(2);           
            getSurfaceType(app); % set app.x_surf, app.y_surf, and app.y_surf_imaging to selected value
            app.random_state = rng; % save random state
            app.default_rand_nums_small_width = [0.9; 0.2];
            app.default_rand_nums_large_width = [0.9; 0.3];
            app.y_center_sim = NaN(1, length(app.x_sim_axes));
            app.y_surf_img_sim = NaN(1, length(app.x_sim_axes));
            app.x_surf_img_sim_tapping = NaN(1, length(app.x_sim_axes));
            app.y_surf_img_sim_tapping = NaN(1, length(app.x_sim_axes));
            app.surf_img_line_width = 1.25;
            app.surf_img_marker_size = 4;
        end
        % ========= End: set default values =========
        
        % ========= Start: slider conversion functions =========
        function y = radiusSliderConversion(app, x)
            % convert radius linear slider value to exponential values between 0 and 1
            % in order to spread out the points at the beginning
            % returns radius value (in units)
            base = 50;
            y = (base^x - 1) / (base - 1);
        end
        
        function y = widthSliderConversion(app, x)
            % convert width slider value to have a minimum value of current
            % tip_radius and maximum value of app.max_half_width
            % returns normalized width value
            tip_radius = getTipRadius(app);
            x_vec = app.WidthSlider.Limits;
            y_vec = [tip_radius, app.max_half_width];
            y = interp1(x_vec, y_vec, x);
        end
        
        function y = animationSpeedSliderConversion(app, x)
            % convert animation speed slider value to have a minimum value
            % of slowRate and maximum value of fast_rate
            slow_rate = 500;
            fast_rate = 50;
            x_vec = app.AnimationspeedSlider.Limits;
            y_vec = [slow_rate, fast_rate];
            y = interp1(x_vec, y_vec, x);
        end        
        % ========= End: slider conversion functions =========

        % ========= Start: functions for tip shape =========        
        function tip_radius = getTipRadius(app)
            % returns normalized tip radius
            tip_radius = radiusSliderConversion(app, app.RadiusSlider.Value) / app.max_tip_radius; % normalized
        end
                                      
        function [xtip, ytip, y_tip_dist] = tipShape(app, center_x, center_y)
            % ========= Start: functions used for tip shape ========= 
            function yline_contaminated = contaminatedTip(app, xline, yline)
                function y = frac_fcn(x_min, x_max, y_min, y_max, x) % polynomial
                    x1 = x_min;
                    y1 = y_min;                    
                    x5 = x_max;
                    y5 = y_max;
                                        
                    x3 = mean([x1, x5]);
                    y_offset = 0.1 * (y5 - y1);
                    y3 = y5 - y_offset;
                    
                    x2 = mean([x1, x3]);
                    y_offset = 0.2 * (y3 - y1);
                    y2 = y3 - y_offset;
                    
                    x4 = mean([x3, x5]);
                    y4 = mean([y3, y5]);
                    
                    x_vec = [x1, x2, x3, x4, x5];
                    y_vec = [y1, y2, y3, y4, y5];
                    
                    p = polyfit(x_vec, y_vec, length(x) - 1);
                    y = polyval(p, x);
                end
                
                rng(app.random_state); % restore previous generator settings
                if app.RandomButton.Value % random button pressed
                    rand_nums = rand(2, 1);
                else
                    if app.WidthSlider.Value <= mean(app.WidthSlider.Limits)
                        rand_nums = app.default_rand_nums_small_width;
                    else
                        rand_nums = app.default_rand_nums_large_width;
                    end
                end
                
                dist = sum(sqrt(diff(xline).^2 + diff(yline).^2));
                
                % lambda and amplitude varies with the tip width
                tip_radius = getTipRadius(app);
                tip_half_width = widthSliderConversion(app, app.WidthSlider.Value);
                frac_min = frac_fcn(tip_radius, app.max_half_width, 0.014, 0.1, tip_half_width);
                frac_max = frac_fcn(tip_radius, app.max_half_width, 0.017, 0.25, tip_half_width);
                lambda_min = frac_min * dist;
                lambda_max = frac_max * dist;
                
                amp = interp1([tip_radius, app.max_half_width], [1, 0.5], tip_half_width);
                
                if max(diff(xline)) == 0 % line is vertical
                    yline_contaminated = yline; % no contamination for vertical lines
                else
                    m = max(diff(yline) ./ diff(xline));
                    
                    if m < 0 % left side
                        indx = length(xline);
                        rand_num = rand_nums(1);
                    elseif m > 0 % right side
                        indx = 1;
                        rand_num = rand_nums(2);
                    end
                    
                    lambda = lambda_min + (lambda_max - lambda_min) * rand_num;
                                    
                    yline_contaminated = -1 * amp * sin(2 * pi * (xline - xline(indx)) / lambda).^2 ...
                        + yline;
                    
                    yline_contaminated(1) = yline(1);
                    yline_contaminated(end) = yline(end);
                end                                             
            end                       
            
            function [xline, yline] = tipLine(startpt, endpt)
                assert(startpt(1) <= endpt(1), ...
                    "x value of startpt must be less than x value of endpt")
                
                deltax_endpts = endpt(1) - startpt(1);
                if deltax_endpts < app.deltax
                    % endpoints are too close together to resolve with app.deltax
                    % move x of endpt to x of startpt
                    endpt(1) = startpt(1);
                end                
                                
                if startpt(1) < endpt(1)
                    yslope = endpt(2) - startpt(2);
                    xslope = endpt(1) - startpt(1);
                    m = yslope / xslope;
                    
                    xline = startpt(1):app.deltax:endpt(1);
                    yline = m * (xline - startpt(1)) + startpt(2);                    
                                        
                    yline(1) = startpt(2); % enforce that first point is at startpt(1)
                    yline(end) = endpt(2); % enforce that the last point is at endpt(2)
                elseif startpt(1) == endpt(1) % vertical straight line
                    num_pts = 20;
                    xline = startpt(1) * ones(1, num_pts);
                    yline = linspace(startpt(2), endpt(2), num_pts);
                end

                if strcmp(app.ContaminatedtipSwitch.Value, 'On')                
                    yline = contaminatedTip(app, xline, yline);                        
                end                
            end
            % ========= End: functions used for tip shape =========
            
            if strcmp(app.ShearedtipSwitch.Value, 'Off') && strcmp(app.MultiplepeakstipSwitch.Value, 'Off') && strcmp(app.HollowtipSwitch.Value, 'Off')
                tip_radius = getTipRadius(app);
                tip_half_width = widthSliderConversion(app, app.WidthSlider.Value);
                tip_height_plot = center_y - tip_radius + app.tip_height;
                
                cir_plot_x = (center_x - tip_radius):app.deltax:(center_x + tip_radius);
                cir_plot_y = -real(sqrt(tip_radius^2 - (cir_plot_x - center_x).^2)) + center_y;
                
                if tip_radius == 0 && tip_half_width == tip_radius % tip becomes a straight line
                    xtip = [cir_plot_x, cir_plot_x];
                    ytip = [cir_plot_y, tip_height_plot];
                else               
                    [xlineleft, ylineleft] = tipLine([center_x - tip_half_width, tip_height_plot], ...
                       [cir_plot_x(1), cir_plot_y(1)]); % left line
                    [xlineright, ylineright] = tipLine([cir_plot_x(end), cir_plot_y(end)], ...
                       [center_x + tip_half_width, tip_height_plot]); % right line
    
                    xtip = [xlineleft, cir_plot_x(2:end-1), xlineright];
                    ytip = [ylineleft, cir_plot_y(2:end-1), ylineright];                
                end
                
                y_tip_dist = tip_radius;
            end
            
            if strcmp(app.ShearedtipSwitch.Value, 'On') % fractured tip
                tip_radius = radiusSliderConversion(app, 0.5) / app.max_tip_radius; % normalized
                tip_half_width = widthSliderConversion(app, 0.5);
                tip_height_plot = center_y - tip_radius + app.tip_height;
                
                slant_plot_x = (center_x - tip_radius):app.deltax:(center_x + tip_radius);
                slant_endpt_y1 = center_y - 0.5 * tip_radius;
                slant_endpt_y2 = center_y + 0.5 * tip_radius;
                m_slant = (slant_endpt_y2 - slant_endpt_y1) / (slant_plot_x(end) - slant_plot_x(1));
                slant_plot_y = m_slant * (slant_plot_x - slant_plot_x(1)) + slant_endpt_y1;

                [xlineleft, ylineleft] = tipLine([center_x - tip_half_width, tip_height_plot], ...
                   [slant_plot_x(1), slant_plot_y(1)]); % left line
                [xlineright, ylineright] = tipLine([slant_plot_x(end), slant_plot_y(end)], ...
                   [center_x + tip_half_width, tip_height_plot]); % right line

                xtip = [xlineleft, slant_plot_x(2:end-1), xlineright];
                ytip = [ylineleft, slant_plot_y(2:end-1), ylineright];                
            
                y_tip_dist = 0.5 * tip_radius;
            end
            
            if strcmp(app.MultiplepeakstipSwitch.Value, 'On') % multiple tips
                tip_radius = radiusSliderConversion(app, 0.5) / app.max_tip_radius; % normalized
                tip_half_width = widthSliderConversion(app, 0.5);
                tip_height_plot = center_y - tip_radius + app.tip_height;
                
                quartic_a = 500;
                quartic_b = 20;
                quartic_c = -12;
                quartic_x = (center_x - tip_radius):app.deltax:(center_x + tip_radius);
                quartic_y = quartic_a * quartic_x.^4 + ...
                    quartic_b * quartic_x.^3 + quartic_c * quartic_x.^2;
                quartic_y = quartic_y + center_y;

                [xlineleft, ylineleft] = tipLine([center_x - tip_half_width, tip_height_plot], ...
                   [quartic_x(1), quartic_y(1)]); % left line
                [xlineright, ylineright] = tipLine([quartic_x(end), quartic_y(end)], ...
                   [center_x + tip_half_width, tip_height_plot]); % right line

                xtip = [xlineleft, quartic_x(2:end-1), xlineright];
                ytip = [ylineleft, quartic_y(2:end-1), ylineright];                
            
                y_tip_dist = abs(min(quartic_y) - center_y);            
            end
            
            if strcmp(app.HollowtipSwitch.Value, 'On') % hollow tips
                tip_radius = radiusSliderConversion(app, 0.5) / app.max_tip_radius; % normalized
                tip_half_width = widthSliderConversion(app, 0.5);
                tip_height_plot = center_y - tip_radius + app.tip_height;
                
                flat_plot_x = (center_x - tip_radius):app.deltax:(center_x + tip_radius);
                flat_plot_y = zeros(size(flat_plot_x));
                flat_plot_y(:) = center_y;
                flat_plot_y(flat_plot_x < center_x - 0.5 * tip_radius | flat_plot_x > center_x + 0.5 * tip_radius) = center_y - tip_radius;

                [xlineleft, ylineleft] = tipLine([center_x - tip_half_width, tip_height_plot], ...
                   [flat_plot_x(1), flat_plot_y(1)]); % left line
                [xlineright, ylineright] = tipLine([flat_plot_x(end), flat_plot_y(end)], ...
                   [center_x + tip_half_width, tip_height_plot]); % right line

                xtip = [xlineleft, flat_plot_x(2:end-1), xlineright];
                ytip = [ylineleft, flat_plot_y(2:end-1), ylineright];                
            
                y_tip_dist = 0.5 * tip_radius;    
            end
        end      
        % ========= End: functions for tip shape =========
        
        % ========= Start: functions to plot Tip Shape =========
        function [center_x, center_y] = plotTipShapeCenters(app, tip_radius)
            center_x = mean(app.TipShapeAxes.XLim);
            center_y = mean(app.TipShapeAxes.YLim) - 0.5 * app.tip_height + tip_radius;
        end
        
        function [center_x, center_y] = plotInitialSimulationFrameCenters(app, tip_radius)
            tip_dist_surf = 0.5;
            center_x = app.x_sim_axes(1);
            center_y = max(app.y_surf) + tip_dist_surf + tip_radius;
        end
        
        function plotTipShape(app, xtip, ytip, ax)            
            fill(ax, xtip, ytip, 'k')        
        end
                
        function updateTipShapeSurface(app)
            tip_radius = getTipRadius(app);
            [center_x, center_y] = plotTipShapeCenters(app, tip_radius);
            [xtip, ytip, ~] = tipShape(app, center_x, center_y);
            
            cla(app.TipShapeAxes)
            hold(app.TipShapeAxes, 'on')            
            plotTipShape(app, xtip, ytip, app.TipShapeAxes)
            plotScaleBar(app, 'TipShapeAxes', 'southeast')
            hold(app.TipShapeAxes, 'off')            
            axis(app.TipShapeAxes, 'square')
                        
            [center_x, center_y] = plotInitialSimulationFrameCenters(app, tip_radius);
            plotSimulationFrame(app, center_x, center_y)
        end
        % ========= End: functions to plot Tip Shape =========
        
        % ========= Start: function to plot scale bar =========
        function plotScaleBar(app, axes_name, location)                                  
            color_vec = [0.44, 0.5, 0.56];
            scale_bar_unitless = app.scale_bar / (app.max_tip_radius * app.max_tip_radius_units);            
            
            text_string = strcat(sprintf('%3.1f', app.scale_bar), ' \mum');
            text_fontsize = 12;
            
            switch axes_name
                case 'TipShapeAxes'
                    ax = app.TipShapeAxes;
                    scale_bar_thickness = 0.1;
                    text_length = 5.5 * scale_bar_unitless;
                case 'SimulationAxes'
                    ax = app.SimulationAxes;
                    scale_bar_thickness = 0.025;
                    text_length = 0.65 * scale_bar_unitless;
                case 'SurfaceImagingAxes'
                    ax = app.SurfaceImagingAxes;
                    scale_bar_thickness = 0.025;
                    text_length = 0.65 * scale_bar_unitless;
            end           
            
            x_length = ax.XLim(2) - ax.XLim(1);
            y_length = ax.YLim(2) - ax.YLim(1);
            
            switch location
                case 'northeast'
                    x_text = 0.99 * x_length + ax.XLim(1) - text_length;
                    
                    x_max = x_text - 0.05 * scale_bar_unitless;
                    x_min = x_max - scale_bar_unitless;
                    y_max = 0.95 * y_length + ax.YLim(1);                                                          
                case 'southeast'
                    x_text = 0.99 * x_length + ax.XLim(1) - text_length;
                    
                    x_max = x_text - 0.05 * scale_bar_unitless;
                    x_min = x_max - scale_bar_unitless;
                    y_max = 0.075 * y_length + ax.YLim(1);                
            end
            y_min = y_max - scale_bar_thickness;
            y_text = mean([y_min, y_max]);
            
            x_vec = [x_min, x_max, x_max, x_min];
            y_vec = [y_max, y_max, y_min, y_min];
            
            patch(ax, x_vec, y_vec, color_vec, 'EdgeColor', 'none')
            text(ax, x_text, y_text, text_string, 'FontSize', text_fontsize, 'Color', color_vec)
        end
        % ========= End: function to plot scale bar =========
        
        % ========= Start: functions for surfaces =========
        function getSurfaceType(app)
            % set app.x_surf, app.y_surf, and app.y_surf_imaging to the selected surface
            switch app.SurfaceprofilesButtonGroup.SelectedObject.Text
                case 'Square'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = squareSurf(app);
                case 'Rectangle'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = rectangleSurf(app);
                case 'Triangle'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = triangleSurf(app);
                case 'Sine'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = cosineSurf(app);  % shifted sine wave
                case 'Semicircle'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = semicircleSurf(app);
                case 'Inverted triangle'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = invertedTriangleSurf(app);
                case 'Random surface'
                    [app.x_surf, app.y_surf, app.y_surf_imaging] = randomSurf(app);
            end
        end
        
        function y_surf = adjustLengthYSurf(app, y_surf, x_surf_len)
            if length(y_surf) < x_surf_len
                % pad end with zeros
                num_extra_pts = x_surf_len - length(y_surf);
                y_surf = [y_surf, zeros([1, num_extra_pts])];
            elseif length(y_surf) > x_surf_len
                % centering and cut off ends
                half_x_surf_len = round(0.5 * x_surf_len);
                half_y_surf_len = round(0.5 * length(y_surf));                
                start_indx = half_y_surf_len - half_x_surf_len;
                end_indx = start_indx + x_surf_len - 1;
                y_surf = y_surf(start_indx:end_indx);
            end            
        end
        
        function [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf)
            % for patch (to be able to draw the lower line of the surface)
            x_surf = [x_surf(1), x_surf, x_surf(end)];
            y_surf = [0, y_surf, 0];
        end
        
        function [x_surf, y_surf, y_surf_img] = squareSurf(app)
            x_surf = app.x_sim_axes;
            
            num_squares = 5;
            y_shift = 0.05;
            x_length = app.x_sim_axes(end) - app.x_sim_axes(1);
            square_length = x_length / (2 * num_squares);
            x_dist = app.x_sim_axes(1) + square_length;
            err_dist = abs(app.x_sim_axes - x_dist);
            [~, num_x_pts] = min(err_dist);
            y_square = [square_length * ones([1, num_x_pts - 1]), zeros([1, num_x_pts - 1])] + y_shift;
            y_surf = repmat(y_square, [1, num_squares]);
            %y_surf(1) = 0;
            y_surf = adjustLengthYSurf(app, y_surf, length(app.x_sim_axes));
            
            y_surf_img = y_surf;
            
            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);
        end
        
        function [x_surf, y_surf, y_surf_img] = rectangleSurf(app)
            x_surf = app.x_sim_axes;
            
            num_squares = 5;
            y_shift = 0.05;             
            
            x_length = x_surf(end) - x_surf(1);
            square_length = x_length / (2 * num_squares);
            x_dist = x_surf(1) + square_length;
            err_dist = abs(x_surf - x_dist);
            [~, num_x_pts] = min(err_dist);
            y_rect = [2 * square_length * ones([1, num_x_pts - 1]), zeros([1, num_x_pts - 1])] + y_shift;
            y_surf = repmat(y_rect, [1, num_squares]);
            %y_surf(1) = 0;
            y_surf = adjustLengthYSurf(app, y_surf, length(x_surf));
            
            y_surf_img = y_surf;
            
            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);            
        end
        
        function [x_surf, y_surf, y_surf_img] = triangleSurf(app)
            x_surf = app.x_sim_axes;
            
            num_triangles = 5;
            y_shift = 0.05;
            x_length = app.x_sim_axes(end) - app.x_sim_axes(1);
            triangle_length = x_length / (num_triangles);
            half_triangle_length = 0.5 * triangle_length;            
            x_triangle = -half_triangle_length:app.deltax:half_triangle_length;
            y_triangle = abs(x_triangle);
            y_triangle = -y_triangle + max(y_triangle);
            y_triangle = [y_triangle, zeros(1, round(0.1 * length(x_triangle)))] + y_shift;
            y_surf = repmat(y_triangle, [1, num_triangles]);
            y_surf = adjustLengthYSurf(app, y_surf, length(app.x_sim_axes));
            
            y_surf_img = y_surf;

            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);
        end
        
        function [x_surf, y_surf, y_surf_img] = cosineSurf(app)
            x_surf = app.x_sim_axes;
            
            A = 0.25;
            num_wavelengths = 5;
            y_shift = 0.05;
            x_length = app.x_sim_axes(end) - app.x_sim_axes(1);
            lambda = x_length / num_wavelengths;
            y_surf = A * cos(2 * pi * app.x_sim_axes / lambda);
            y_surf = y_surf + abs(min(y_surf)) + y_shift;
            y_surf = adjustLengthYSurf(app, y_surf, length(app.x_sim_axes));
            
            y_surf_img = y_surf;
            
            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);
        end
        
        function [x_surf, y_surf, y_surf_img] = semicircleSurf(app)
            x_surf = app.x_sim_axes;
            
            R = 0.5;
            y_shift = 0.05;
            y_surf = sqrt(R^2 - ((2 * R / pi) * acos(cos(pi * app.x_sim_axes / (2 * R))) - R).^2);
            y_surf = -y_surf + max(y_surf) + y_shift;
            y_surf = adjustLengthYSurf(app, y_surf, length(app.x_sim_axes));
            
            y_surf_img = y_surf;

            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);
        end
        
        function [x_surf, y_surf, y_surf_img] = invertedTriangleSurf(app)            
            num_triangles = 5;
            y_shift = 0.05;
            
            % slanted lines
            x_length = app.x_sim_axes(end) - app.x_sim_axes(1);
            triangle_length = x_length / (num_triangles);
            half_triangle_length = 0.5 * triangle_length;            
            x_triangle = -half_triangle_length:app.deltax:half_triangle_length;
            y_triangle = abs(x_triangle);
            y_triangle = y_triangle - 0.1 * max(y_triangle);
            
            half_indx = floor(0.5 * length(y_triangle));
            x_triangle_left = app.x_sim_axes(1:half_indx);
            x_triangle_right = app.x_sim_axes((half_indx + 1):end);
            y_triangle_left = y_triangle(1:half_indx);
            y_triangle_right = y_triangle((half_indx + 1):end);
            indx_pos_left = find(y_triangle_left >= 0);
            indx_pos_right = find(y_triangle_right >= 0);            
            x_triangle_left = x_triangle_left(indx_pos_left);
            y_triangle_left = y_triangle_left(indx_pos_left);
            x_triangle_right = x_triangle_right(indx_pos_right);
            y_triangle_right = y_triangle_right(indx_pos_right);
            
            % horizontal left line and right            
            x_left_line = min(x_triangle_left):app.deltax:(max(x_triangle_left) - app.deltax);
            y_left_line = min(y_triangle_left) * ones(1, length(x_left_line));           
            
            % horizontal right line
            horz_offset = 1 * half_triangle_length;
            x_right_indx_start = app.x_sim_axes > min(x_triangle_right);
            x_right_start = min(app.x_sim_axes(x_right_indx_start));
            x_right_line = x_right_start:app.deltax:(max(x_triangle_right) + horz_offset);
            y_right_line = min(y_triangle_right) * ones(1, length(x_right_line));
            
            % top line
            x_top_line_indx = (app.x_sim_axes > min(x_triangle_left) & app.x_sim_axes < max(x_triangle_right));
            x_top_line = app.x_sim_axes(x_top_line_indx);            
            y_top_line = max(y_triangle_left) * ones(1, length(x_top_line));
            
            % combining
            x_surf_triangle = [x_left_line, x_triangle_left, x_top_line, x_triangle_right, x_right_line];
            y_surf_triangle = [y_left_line, y_triangle_left, y_top_line, y_triangle_right, y_right_line] + y_shift;
            
            % replicating
            x_surf = x_surf_triangle;
            for i_triangle = 2:num_triangles
                x_offset_indx = (app.x_sim_axes > x_surf(end));
                x_offset = min(app.x_sim_axes(x_offset_indx));
                if ~isempty(x_offset)
                    x_surf_triangle_shift = x_surf_triangle + x_offset;
                    x_surf = [x_surf, x_surf_triangle_shift];
                else
                    break
                end
            end
            y_surf = repmat(y_surf_triangle, [1, (i_triangle - 1)]);
            
            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);
            
            % surface image
            % need to find the max y for each x
            y_surf_img = NaN(1, length(app.x_sim_axes));
            round_digits = abs(log10(app.deltax)) + 1;
            x_surf_round = round(x_surf, round_digits);
            for i = 1:length(app.x_sim_axes)
                x = round(app.x_sim_axes(i), round_digits);                
                x_indx = (x_surf_round == x);
                y_surf_x = y_surf(x_indx);
                y_surf_img(i) = max(y_surf_x);               
            end
        end
        
        function [x_surf, y_surf, y_surf_img] = randomSurf(app)
            x_surf = app.x_sim_axes;
            
            num_squares = 5;
            y_shift = 0.05;
            x_length = app.x_sim_axes(end) - app.x_sim_axes(1);
            square_length = x_length / (2 * num_squares);
            x_dist = app.x_sim_axes(1) + square_length;
            err_dist = abs(app.x_sim_axes - x_dist);
            [~, num_x_pts] = min(err_dist);
            
            height = [1, 2, 0.5, 1.25, 0.25, 0.75, 1, 0.5, 1.5, 0.125];
            y_surf = [];
            for i = 1:(num_squares)               
                y_square_i = height(i) * square_length * ones([1, num_x_pts - 1]) + y_shift;
                y_surf = [y_surf, y_square_i];
            end
            
            half_triangle_length = 0.5 * square_length;            
            x_triangle = -half_triangle_length:app.deltax:half_triangle_length;
            y_triangle = abs(x_triangle);
            y_triangle = -y_triangle + max(y_triangle) + y_shift + height(6);
            
            y_surf = [y_surf, y_triangle];
            
            for i = (num_squares + 2):(num_squares * 2)
                y_square_i = height(i) * square_length * ones([1, num_x_pts - 1]) + y_shift;
                y_surf = [y_surf, y_square_i];                
            end
            
            y_surf = adjustLengthYSurf(app, y_surf, length(app.x_sim_axes));

            noise_intensity = 0.1;
            windowSize = 100;
            noise = randn(1, length(y_surf)) * noise_intensity;
            smooth_noise = smooth(noise, windowSize);
            y_surf = y_surf + smooth_noise';
            
            y_surf_img = y_surf;
            
            % adding endpoints
            [x_surf, y_surf] = addEndpoints(app, x_surf, y_surf);
        end        
        % ========= End: functions for surfaces =========
        
        % ========= Start: surface plot function =========
        function plotSurface(app)
            % assumes that app.x_surf, app.y_surf have already been set to
            % the correct surface
            patch(app.SimulationAxes, app.x_surf, app.y_surf, ...
                'b', 'EdgeColor', 'none')
            axis(app.SimulationAxes, [app.SimulationAxes.XLim(1), ...
                app.SimulationAxes.XLim(2), ...
                app.SimulationAxes.YLim(1), ...
                app.SimulationAxes.YLim(2)])

            % z_surf = zeros(size(app.x_surf));
            % fill3(app.SimulationAxes, app.x_surf, app.y_surf, z_surf, ...
            %     'b', 'EdgeColor', 'none')
            % axis(app.SimulationAxes, [app.SimulationAxes.XLim(1), ...
            %     app.SimulationAxes.XLim(2), ...
            %     app.SimulationAxes.YLim(1), ...
            %     app.SimulationAxes.YLim(2), ...
            %     app.SimulationAxes.ZLim(1), ...
            %     app.SimulationAxes.ZLim(2)])          
        end
        % ========= End: surface plot function =========
        
        % ========= Start: enable / disable buttons functions =========
        function enableDisableButtons(app, onoffstr)           
            app.SurfaceprofilesButtonGroup.Enable = onoffstr;
            app.RadiusSlider.Enable = onoffstr;
            app.WidthSlider.Enable = onoffstr;            
            app.ContaminatedtipSwitch.Enable = onoffstr;
            app.ShearedtipSwitch.Enable = onoffstr;
            app.MultiplepeakstipSwitch.Enable = onoffstr;
            app.HollowtipSwitch.Enable = onoffstr;
            app.RandomButton.Enable = onoffstr;
            app.AnimationKnob.Enable = onoffstr;
            app.AnimationspeedSlider.Enable = onoffstr;
            app.TappingstepsizemicromSpinner.Enable = onoffstr;
            app.ResetButton.Enable = onoffstr;
        end
        
        function enableDisableBrokenTipOn(app, contaminated_tip_switch_value, sheared_tip_switch_value, multiple_peaks_tip_switch_value, hollow_tip_switch_value)
            if strcmp(contaminated_tip_switch_value, 'On') || strcmp(sheared_tip_switch_value, 'On') || strcmp(multiple_peaks_tip_switch_value, 'On') || strcmp(hollow_tip_switch_value, 'On')
                app.RadiusSlider.Value = 0.5;
                app.WidthSlider.Value = 0.5;        
                
                app.RadiusSlider.Enable = 'off';
                app.WidthSlider.Enable = 'off';
                if strcmp(contaminated_tip_switch_value, 'On')
                    app.MultiplepeakstipSwitch.Enable = 'off';
                    app.HollowtipSwitch.Enable = 'off';
                    app.ShearedtipSwitch.Enable = 'off';
                elseif strcmp(sheared_tip_switch_value, 'On')
                    app.MultiplepeakstipSwitch.Enable = 'off';
                    app.HollowtipSwitch.Enable = 'off';
                    app.ContaminatedtipSwitch.Enable = 'off';
                elseif strcmp(multiple_peaks_tip_switch_value, 'On')
                    app.ShearedtipSwitch.Enable = 'off';
                    app.HollowtipSwitch.Enable = 'off';
                    app.ContaminatedtipSwitch.Enable = 'off';
                elseif strcmp(hollow_tip_switch_value, 'On')
                    app.ShearedtipSwitch.Enable = 'off';
                    app.MultiplepeakstipSwitch.Enable = 'off';
                    app.ContaminatedtipSwitch.Enable = 'off';
                end
            else
                app.RadiusSlider.Enable = 'on';
                app.WidthSlider.Enable = 'on';
                app.ContaminatedtipSwitch.Enable = 'on';
                app.ShearedtipSwitch.Enable = 'on';
                app.MultiplepeakstipSwitch.Enable = 'on';
                app.HollowtipSwitch.Enable = 'on';
            end            
        end        
        
        function contaminatedTipRandomButtonEnableDisable(app)
            if strcmp(app.ContaminatedtipSwitch.Value, 'On')
                app.RandomButton.Enable = 'on';
            else
                app.RandomButton.Enable = 'off';
            end            
        end
        
        function animationKnobEnableDisable(app)
            switch app.AnimationKnob.Value
                case 'Off'
                    app.AnimationspeedSlider.Enable = 'off';
                    app.TappingstepsizemicromSpinner.Enable = 'off';
                case 'Contact'
                    app.AnimationspeedSlider.Enable = 'on';
                    app.TappingstepsizemicromSpinner.Enable = 'off';
                case 'Tapping'
                    app.AnimationspeedSlider.Enable = 'on';
                    app.TappingstepsizemicromSpinner.Enable = 'on';
            end
        end
        % ========= End: enable / disable buttons functions =========
        
        % ========= Start: simulation functions =========        
        function surf_matrix = createSurfaceMatrix(app, y_surf, len_tip_vec)
            surf_start_indx = 1:(length(y_surf) - len_tip_vec + 1);
            surf_end_indx = surf_start_indx + len_tip_vec - 1;
            indx = [];
            for i = 1:length(surf_start_indx)
                s = surf_start_indx(i);
                e = surf_end_indx(i);
                indx = [indx, s:e];
            end
            y_surf_vector = y_surf(indx);          
            surf_matrix = transpose(reshape(y_surf_vector, len_tip_vec, []));            
        end
        
        function clearData(app)
            % clear data if data has not been processed
            app.y_center_sim = NaN(1, length(app.x_sim_axes));
            app.y_surf_img_sim = NaN(1, length(app.x_sim_axes));
            app.x_surf_img_sim_tapping = NaN(1, length(app.x_sim_axes));
            app.y_surf_img_sim_tapping = NaN(1, length(app.x_sim_axes));                           
        end
                
        function plotSimulationFrame(app, center_x, center_y)
            % plots the selected surface and tip with position
            % center_x and center_y and the surface image (if processed)
            cla(app.SimulationAxes)
            cla(app.SurfaceImagingAxes)
            
            % simulation axes
            hold(app.SimulationAxes, 'on')
            plotSurface(app)
            [xtip, ytip, ~] = tipShape(app, center_x, center_y);
            plotTipShape(app, xtip, ytip, app.SimulationAxes)
            plotScaleBar(app, 'SurfaceImagingAxes', 'northeast')
            hold(app.SimulationAxes, 'off')
            
            % surface image axes           
            hold(app.SurfaceImagingAxes, 'on')
            
            switch app.AnimationKnob.Value
                case 'Off'
                    x_surface_image = app.x_sim_axes;
                    y_surface_image = app.y_surf_img_sim;
                    
                    plot(app.SurfaceImagingAxes, x_surface_image, y_surface_image, ...
                        'r', 'LineWidth', app.surf_img_line_width)                    
                case 'Contact'
                    x_surface_image = app.x_sim_axes;
                    y_surface_image = app.y_surf_img_sim;
                    
                    plot(app.SurfaceImagingAxes, x_surface_image, y_surface_image, ...
                        'r', 'LineWidth', app.surf_img_line_width)                     
                case 'Tapping'
                    x_surface_image = app.x_surf_img_sim_tapping;
                    y_surface_image = app.y_surf_img_sim_tapping;
                    
                    plot(app.SurfaceImagingAxes, x_surface_image, y_surface_image, ...
                        'Marker', 'o', 'MarkerSize', app.surf_img_marker_size, ...
                        'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r', ...
                        'LineStyle', '-', 'LineWidth', app.surf_img_line_width, ...
                        'Color', 'r'); 
            end
            
            plotScaleBar(app, 'SimulationAxes', 'northeast')
            hold(app.SurfaceImagingAxes, 'off')
        end
        % ========= End: simulation functions =========
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setDefaultValues(app)           
            updateTipShapeSurface(app)
            contaminatedTipRandomButtonEnableDisable(app)
            animationKnobEnableDisable(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            enableDisableButtons(app, 'off') % disable other buttons
            app.SkipAnimationButton.Value = false; %reset the button state
            
            % tip shape
            center_x = min(app.x_sim_axes);
            center_y = max(app.y_surf) + 2 * app.max_tip_radius;            
            [~, y_tip, y_tip_dist] = tipShape(app, center_x, center_y);
            
            % create surface matrix
            padding_length_left = ceil(0.5 * length(y_tip));
            padding_length_right = length(y_tip) - padding_length_left - 1;            
            y_surf_padding = [zeros(1, padding_length_left), ...
                app.y_surf_imaging, zeros(1, padding_length_right)];
            surf_matrix = createSurfaceMatrix(app, y_surf_padding, length(y_tip));
            
            % find minimum distances and surface image
            min_dists = min((y_tip - surf_matrix), [], 2);
            app.y_center_sim = transpose(center_y - min_dists);
            app.y_surf_img_sim = app.y_center_sim - y_tip_dist;
            
            if strcmp(app.AnimationKnob.Value, 'Off')
                plot(app.SurfaceImagingAxes, app.x_sim_axes, app.y_surf_img_sim, ...
                        'r', 'LineWidth', app.surf_img_line_width)                            
            else % animation
                cla(app.SimulationAxes)
                cla(app.SurfaceImagingAxes)
                
                tip_radius = getTipRadius(app);
                [center_x_init, center_y_init] = plotInitialSimulationFrameCenters(app, tip_radius);
                
                % plot first frame
                center_x1 = center_x_init;
                center_y1 = center_y_init;                   
                [xtip, ytip, ~] = tipShape(app, center_x1, center_y1);
                
                plotSurface(app)
                hold(app.SimulationAxes, 'on')
                plotScaleBar(app, 'SimulationAxes', 'northeast')
                h_tip_obj = hgtransform(app.SimulationAxes, 'Parent', app.SimulationAxes); % transform object
                patch(app.SimulationAxes, xtip, ytip, 'k', 'Parent', h_tip_obj);
                hold(app.SimulationAxes, 'off')
                
                plotScaleBar(app, 'SurfaceImagingAxes', 'northeast')
                
                % animations
                switch app.AnimationKnob.Value
                    case 'Contact'                        
                        % move down animation                        
                        move_down_y = center_y_init;
                        move_down_x = center_x_init;
                        len_move_down = length(move_down_y);
                        
                        % surface contact animation
                        x_animation = [move_down_x, app.x_sim_axes];
                        y_animation = [move_down_y, app.y_center_sim];
                        
                        x_surf_img_animation = [NaN(1, len_move_down), app.x_sim_axes]; % add NaN to sync animations
                        y_surf_img_animation = [NaN(1, len_move_down), app.y_surf_img_sim];
                        
                        h_surf_img_sim = animatedline(app.SimulationAxes, ...
                            'Color', 'r', 'LineWidth', app.surf_img_line_width);               
                        h_surf_img_surf = animatedline(app.SurfaceImagingAxes, ...
                            'Color', 'r', 'LineWidth', app.surf_img_line_width);                        
                    case 'Tapping'                       
                        step_size_normalized = round(app.TappingstepsizemicromSpinner.Value / (app.max_tip_radius * app.max_tip_radius_units), ...
                            abs(log10(app.deltax)));
                        step_size_indx = step_size_normalized * 1 / app.deltax;
                        
                        num_inbetween_pts = round(0.75 * step_size_indx);
                        total_pts = 2 * num_inbetween_pts + 3; % * 2 for each side, + 3 for endpoints and tap point                        
                        
                        % surface tapping animation                        
                        app.x_surf_img_sim_tapping = app.x_sim_axes(1:step_size_indx:end);
                        app.y_surf_img_sim_tapping = app.y_surf_img_sim(1:step_size_indx:end);
                        y_center_tapping = app.y_center_sim(1:step_size_indx:end);
                        
                        x_animation = [];
                        y_animation = [];
                        
                        x_surf_img_animation = [];
                        y_surf_img_animation = [];                        
                        
                        for itap = 1:length(app.x_surf_img_sim_tapping)
                            % move down and up on each x
                            x_tap = app.x_surf_img_sim_tapping(itap);
                            y_tap = app.y_surf_img_sim_tapping(itap);
                            y_cntr_tap = y_center_tapping(itap);
                            
                            x_animation_itap = x_tap * ones(1, total_pts);
                            y_animation_pts = linspace(center_y_init, y_cntr_tap, num_inbetween_pts + 2);
                            y_animation_itap = [center_y_init, y_animation_pts(2:end - 1), ...
                                y_cntr_tap, y_animation_pts(end - 1:-1:2), center_y_init];
                            
                            nan_vec = NaN(1, num_inbetween_pts + 1);
                            x_surf_img_animation_itap = [nan_vec, x_tap, nan_vec];
                            y_surf_img_animation_itap = [nan_vec, y_tap, nan_vec];
                            
                            % move to next point
                            if itap < length(app.x_surf_img_sim_tapping) % next point
                                x_tap_next = app.x_surf_img_sim_tapping(itap + 1);                                                                                              
                            else % end of screen
                                x_tap_next = app.x_sim_axes(end);
                            end
                            x_dist = x_tap_next - x_tap;
                            y_dist = center_y_init - y_cntr_tap;
                            num_pts = round(num_inbetween_pts * (x_dist / y_dist));
                            x_animation_nextpt = linspace(x_tap, x_tap_next, num_pts + 2);
                            x_animation_nextpt = x_animation_nextpt(2:end - 1); % take off endpoints
                            y_animation_nextpt = center_y_init * ones(1, num_pts);
                            
                            x_surf_img_nextpt = NaN(1, num_pts);
                            y_surf_img_nextpt = NaN(1, num_pts);
                                                          
                            x_animation = [x_animation, x_animation_itap, x_animation_nextpt];
                            y_animation = [y_animation, y_animation_itap, y_animation_nextpt];                       
                            
                            x_surf_img_animation = [x_surf_img_animation, ...
                                x_surf_img_animation_itap, x_surf_img_nextpt];
                            y_surf_img_animation = [y_surf_img_animation, ...
                                y_surf_img_animation_itap, y_surf_img_nextpt];
                        end                       
                                                
                        h_surf_img_sim = animatedline(app.SimulationAxes, ...
                            'Marker', 'o', 'MarkerSize', app.surf_img_marker_size, ...
                            'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r', ...
                            'LineStyle', 'none');               
                        h_surf_img_surf = animatedline(app.SurfaceImagingAxes, ...
                            'Marker', 'o', 'MarkerSize', app.surf_img_marker_size, ...
                            'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r', ...
                            'LineStyle', 'none');                        
                end  
                
                rate = animationSpeedSliderConversion(app, app.AnimationspeedSlider.Value);
                a = tic;
                for i = 2:length(x_animation)
                    if app.SkipAnimationButton.Value % if cancel button is pressed
                        app.SkipAnimationButton.Value = false; %reset the button state
                        break                
                    end
                    
                    center_x = x_animation(i);
                    center_y = y_animation(i);
                    
                    m = makehgtform('translate', ...
                        [center_x - center_x1, center_y - center_y1, 0]);
                    h_tip_obj.Matrix = m;
                    
                    addpoints(h_surf_img_sim, x_surf_img_animation(i), y_surf_img_animation(i));
                    addpoints(h_surf_img_surf, x_surf_img_animation(i), y_surf_img_animation(i));
                    
                    b = toc(a);
                    if b > (1 / rate)
                        drawnow
                        a = tic;
                    end
                end
                drawnow % draw last frame                 
            end            
            
            enableDisableButtons(app, 'on') % enable other buttons
            contaminatedTipRandomButtonEnableDisable(app)
            enableDisableBrokenTipOn(app, app.ContaminatedtipSwitch.Value, app.ShearedtipSwitch.Value, app.MultiplepeakstipSwitch.Value, app.HollowtipSwitch.Value)
            animationKnobEnableDisable(app)
            updateTipShapeSurface(app)
        end

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            app.random_state = rng;
            clearData(app)
            updateTipShapeSurface(app)          
        end

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            app.random_state = rng;
            clearData(app)
            updateTipShapeSurface(app)           
        end

        % Selection changed function: SurfaceprofilesButtonGroup
        function SurfaceprofilesButtonGroupSelectionChanged(app, event)
            clearData(app)
            getSurfaceType(app)
            updateTipShapeSurface(app)
        end

        % Value changed function: ContaminatedtipSwitch
        function ContaminatedtipSwitchValueChanged(app, event)
            app.ShearedtipSwitch.Value = 'Off';
            app.MultiplepeakstipSwitch.Value = 'Off';
            app.HollowtipSwitch.Value = 'Off';
            enableDisableBrokenTipOn(app, app.ContaminatedtipSwitch.Value, app.ShearedtipSwitch.Value, app.MultiplepeakstipSwitch.Value, app.HollowtipSwitch.Value)
            app.random_state = rng;
            clearData(app)
            updateTipShapeSurface(app)
            contaminatedTipRandomButtonEnableDisable(app)
        end

        % Value changed function: RandomButton
        function RandomButtonValueChanged(app, event)
            app.random_state = rng;
            clearData(app)
            updateTipShapeSurface(app)        
        end

        % Value changed function: AnimationKnob
        function AnimationKnobValueChanged(app, event)
            clearData(app)
            updateTipShapeSurface(app)
            animationKnobEnableDisable(app)        
        end

        % Value changed function: AnimationspeedSlider
        function AnimationspeedSliderValueChanged(app, event)
            clearData(app)
            updateTipShapeSurface(app)
        end

        % Value changed function: TappingstepsizemicromSpinner
        function TappingstepsizemicromSpinnerValueChanged(app, event)
            clearData(app)
            updateTipShapeSurface(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clearData(app)
            app.RadiusSlider.Value = 0.5;
            app.WidthSlider.Value = 0.5;
            app.SquareButton.Value = true;
            app.ContaminatedtipSwitch.Value = 'Off';
            app.ShearedtipSwitch.Value = 'Off';
            app.MultiplepeakstipSwitch.Value = 'Off';
            app.HollowtipSwitch.Value = 'Off';
            app.AnimationKnob.Value = 'Off';
            app.AnimationspeedSlider.Value = 0.5;
            app.TappingstepsizemicromSpinner.Value = 0.5;
            enableDisableBrokenTipOn(app, app.ContaminatedtipSwitch.Value, app.ShearedtipSwitch.Value, app.MultiplepeakstipSwitch.Value, app.HollowtipSwitch.Value)
            getSurfaceType(app)
            updateTipShapeSurface(app)
        end

        % Value changed function: ShearedtipSwitch
        function ShearedtipSwitchValueChanged(app, event)
            app.MultiplepeakstipSwitch.Value = 'Off';
            app.ContaminatedtipSwitch.Value = 'Off';
            app.HollowtipSwitch.Value = 'Off';
            enableDisableBrokenTipOn(app, app.ContaminatedtipSwitch.Value, app.ShearedtipSwitch.Value, app.MultiplepeakstipSwitch.Value, app.HollowtipSwitch.Value)
            clearData(app)         
            updateTipShapeSurface(app)
        end

        % Value changed function: MultiplepeakstipSwitch
        function MultiplepeakstipSwitchValueChanged(app, event)
            app.ShearedtipSwitch.Value = 'Off';
            app.ContaminatedtipSwitch.Value = 'Off';
            app.HollowtipSwitch.Value = 'Off';
            enableDisableBrokenTipOn(app, app.ContaminatedtipSwitch.Value, app.ShearedtipSwitch.Value, app.MultiplepeakstipSwitch.Value, app.HollowtipSwitch.Value)
            clearData(app)
            updateTipShapeSurface(app)           
        end

        % Value changed function: HollowtipSwitch
        function HollowtipSwitchValueChanged(app, event)
            app.ShearedtipSwitch.Value = 'Off';
            app.ContaminatedtipSwitch.Value = 'Off';
            app.MultiplepeakstipSwitch.Value = 'Off';
            enableDisableBrokenTipOn(app, app.ContaminatedtipSwitch.Value, app.ShearedtipSwitch.Value, app.MultiplepeakstipSwitch.Value, app.HollowtipSwitch.Value)
            clearData(app)
            updateTipShapeSurface(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            title(app.SimulationAxes, 'Simulation')
            app.SimulationAxes.XLim = [0 5];
            app.SimulationAxes.YLim = [0 2];
            app.SimulationAxes.XTick = [];
            app.SimulationAxes.YTick = [];
            app.SimulationAxes.ZTick = [];
            app.SurfaceImagingAxes = uiaxes(app.UIFigure);
            title(app.SurfaceImagingAxes, 'Surface Imaging')
            app.SurfaceImagingAxes.XLim = [0 5];
            app.SurfaceImagingAxes.YLim = [0 2];
            app.SurfaceImagingAxes.XTick = [];
            app.SurfaceImagingAxes.YTick = [];
            app.TipShapeAxes = uiaxes(app.UIFigure);
            title(app.TipShapeAxes, 'Tip Shape')
            app.TipShapeAxes.XLim = [-5 5];
            app.TipShapeAxes.YLim = [-5 5];
            app.TipShapeAxes.XTick = [];
            app.TipShapeAxes.YTick = [];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SurfaceprofilesButtonGroup = uibuttongroup(app.UIFigure);
            app.SurfaceprofilesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SurfaceprofilesButtonGroupSelectionChanged, true);
            app.SurfaceprofilesButtonGroup.Title = 'Surface profiles';
            app.SquareButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.TriangleButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.SineButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SemicircleButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.SemicircleButton.Text = 'Semicircle';
            app.InvertedtriangleButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.InvertedtriangleButton.Text = 'Inverted triangle';
            app.RectangleButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RandomsurfaceButton = uiradiobutton(app.SurfaceprofilesButtonGroup);
            app.RandomsurfaceButton.Text = 'Random surface';
            app.RadiusSliderLabel = uilabel(app.UIFigure);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [0 1];
            app.RadiusSlider.MajorTicks = [0 0.5 1];
            app.RadiusSlider.MajorTickLabels = {'Small', '', 'Large'};
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.MinorTicks = [0.125 0.25 0.375 0.625 0.75 0.875];
            app.RadiusSlider.Value = 0.5;
            app.WidthSliderLabel = uilabel(app.UIFigure);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.UIFigure);
            app.WidthSlider.Limits = [0 1];
            app.WidthSlider.MajorTicks = [0 0.5 1];
            app.WidthSlider.MajorTickLabels = {'Thin', '', 'Thick'};
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.MinorTicks = [0.125 0.25 0.375 0.625 0.75 0.875];
            app.WidthSlider.Value = 0.5;
            app.AnimationspeedSliderLabel = uilabel(app.UIFigure);
            app.AnimationspeedSliderLabel.Text = 'Animation speed';
            app.AnimationspeedSlider = uislider(app.UIFigure);
            app.AnimationspeedSlider.Limits = [0 1];
            app.AnimationspeedSlider.MajorTicks = [0 0.5 1];
            app.AnimationspeedSlider.MajorTickLabels = {'Slow', '', 'Fast'};
            app.AnimationspeedSlider.ValueChangedFcn = createCallbackFcn(app, @AnimationspeedSliderValueChanged, true);
            app.AnimationspeedSlider.MinorTicks = [0.125 0.25 0.375 0.625 0.75 0.875];
            app.AnimationspeedSlider.Value = 0.5;
            app.SkipAnimationButton = uibutton(app.UIFigure, 'state');
            app.SkipAnimationButton.Text = 'Skip Animation';
            app.ContaminatedtipSwitchLabel = uilabel(app.UIFigure);
            app.ContaminatedtipSwitchLabel.Text = 'Contaminated tip';
            app.ContaminatedtipSwitch = uiswitch(app.UIFigure, 'slider');
            app.ContaminatedtipSwitch.ValueChangedFcn = createCallbackFcn(app, @ContaminatedtipSwitchValueChanged, true);
            app.RandomButton = uibutton(app.UIFigure, 'state');
            app.RandomButton.ValueChangedFcn = createCallbackFcn(app, @RandomButtonValueChanged, true);
            app.RandomButton.Enable = 'off';
            app.RandomButton.Visible = 'off';
            app.RandomButton.Text = 'Random';
            app.AnimationKnobLabel = uilabel(app.UIFigure);
            app.AnimationKnobLabel.Text = 'Animation';
            app.AnimationKnob = uiknob(app.UIFigure, 'discrete');
            app.AnimationKnob.Items = {'Off', 'Contact', 'Tapping'};
            app.AnimationKnob.ValueChangedFcn = createCallbackFcn(app, @AnimationKnobValueChanged, true);
            app.TappingstepsizemicromSpinnerLabel = uilabel(app.UIFigure);
            app.TappingstepsizemicromSpinnerLabel.Text = {'Tapping step size (&micro;m)'; ''};
            app.TappingstepsizemicromSpinner = uispinner(app.UIFigure);
            app.TappingstepsizemicromSpinner.Step = 0.5;
            app.TappingstepsizemicromSpinner.Limits = [0.5 20];
            app.TappingstepsizemicromSpinner.ValueChangedFcn = createCallbackFcn(app, @TappingstepsizemicromSpinnerValueChanged, true);
            app.TappingstepsizemicromSpinner.Value = 0.5;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShearedtipSwitchLabel = uilabel(app.UIFigure);
            app.ShearedtipSwitchLabel.Text = 'Sheared tip';
            app.ShearedtipSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShearedtipSwitch.ValueChangedFcn = createCallbackFcn(app, @ShearedtipSwitchValueChanged, true);
            app.MultiplepeakstipSwitchLabel = uilabel(app.UIFigure);
            app.MultiplepeakstipSwitchLabel.Text = 'Multiple peaks tip';
            app.MultiplepeakstipSwitch = uiswitch(app.UIFigure, 'slider');
            app.MultiplepeakstipSwitch.ValueChangedFcn = createCallbackFcn(app, @MultiplepeakstipSwitchValueChanged, true);
            app.HollowtipSwitchLabel = uilabel(app.UIFigure);
            app.HollowtipSwitchLabel.Text = 'Hollow tip';
            app.HollowtipSwitch = uiswitch(app.UIFigure, 'slider');
            app.HollowtipSwitch.ValueChangedFcn = createCallbackFcn(app, @HollowtipSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFM_simulation_3d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end